classdef RLC_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ButtonMinus_2             matlab.ui.control.Button
        ButtonPlus_2              matlab.ui.control.Button
        ButtonMinus               matlab.ui.control.Button
        ButtonPlus                matlab.ui.control.Button
        HzLabel                   matlab.ui.control.Label
        FLabel                    matlab.ui.control.Label
        HLabel                    matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        VLabel                    matlab.ui.control.Label
        ModeSwitch                matlab.ui.control.Switch
        ModeSwitchLabel           matlab.ui.control.Label
        HsVCCheckBox              matlab.ui.control.CheckBox
        HsVLCheckBox              matlab.ui.control.CheckBox
        HsVRCheckBox              matlab.ui.control.CheckBox
        frequencyEditField        matlab.ui.control.NumericEditField
        frequencyEditFieldLabel   matlab.ui.control.Label
        PlotButton                matlab.ui.control.Button
        VCCheckBox                matlab.ui.control.CheckBox
        VLCheckBox                matlab.ui.control.CheckBox
        VRCheckBox                matlab.ui.control.CheckBox
        VsCheckBox                matlab.ui.control.CheckBox
        InputSignalDropDown       matlab.ui.control.DropDown
        InputSignalDropDownLabel  matlab.ui.control.Label
        CEditField                matlab.ui.control.NumericEditField
        CEditFieldLabel           matlab.ui.control.Label
        LEditField                matlab.ui.control.NumericEditField
        LEditFieldLabel           matlab.ui.control.Label
        REditField                matlab.ui.control.NumericEditField
        REditFieldLabel           matlab.ui.control.Label
        VsEditField               matlab.ui.control.NumericEditField
        VsEditFieldLabel          matlab.ui.control.Label
        FrequencyAxes             matlab.ui.control.UIAxes
        TimeAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        omega_d;
        Max;
    end
    
    methods (Access = private)
        function Series_results(app)
            syms V_s(t) R L C V_r(t) V_l(t) V_c(t) t I(t) f_T(t);
            % get value
            Amp = app.VsEditField.Value;
            R = app.REditField.Value;
            L = app.LEditField.Value;
            C = app.CEditField.Value;
            f = app.frequencyEditField.Value;
            % defind fuction
            u(t) = piecewise(t >= 0, 1,0);
            % setting V_s(t)
            if app.InputSignalDropDown.Value == "Step"
                V_s(t) = Amp;
                app.Max = Amp;
                ylim(app.TimeAxes,[-app.Max*2/5 app.Max*7/5]);
                alpha = 2*(R/L);
                omega_0 = sqrt(1/(L*C));
                app.omega_d = abs(sqrt(omega_0^2-alpha^2));
                xlim(app.TimeAxes,[-pi/app.omega_d 6*pi/app.omega_d]);
            elseif app.InputSignalDropDown.Value == "Sin"
                V_s(t) = Amp*sin(2*pi*t*f);
                app.Max = Amp;
                ylim(app.TimeAxes,[-app.Max*6/5 app.Max*6/5]);
                app.omega_d = f;
                xlim(app.TimeAxes,[-0.5/app.omega_d 3.5/app.omega_d]);
            end
            % solving V_c
            D2V_c(t) = diff(V_c,t,2);
            D1V_c(t) = diff(V_c,t,1);
            ODE = D2V_c + (R/L)*D1V_c + V_c(t)/(L*C) == V_s/(L*C);
            IC = [V_c(0) == 0 D1V_c(0) == 0];
            V_c(t) = simplify(dsolve(ODE,IC));
            % solving I(t) ODE
            D2I(t) = diff(I,t,2);
            D1I(t) = diff(I,t,1);
            ODE = D2I + (R/L)*D1I + I(t)/(L*C) == diff(V_s,t,1)/L;
            IC = [I(0) == 0, D1I(0) == V_s(0)/L];
            I(t) = simplify(dsolve(ODE,IC));
            V_r(t) = I(t)*R;
            V_l(t) = V_s(t) - V_r(t) - V_c(t);
            % plot time domain
            legendInfo_t = cell(0,1);
            cla(app.TimeAxes);
            hold(app.TimeAxes,"on");
            if app.VsCheckBox.Value
                fplot(app.TimeAxes,V_s(t)*u(t));
                legendInfo_t{end+1} = "V_s (V)";
            end
            if app.VRCheckBox.Value
                fplot(app.TimeAxes,V_r(t)*u(t),'r');
                legendInfo_t{end+1} = "V_r (V)";
            end
            if app.VLCheckBox.Value
                fplot(app.TimeAxes,V_l(t)*u(t),'g');
                legendInfo_t{end+1} = "V_l (V)";
            end
            if app.VCCheckBox.Value
                fplot(app.TimeAxes,V_c(t)*u(t),'b');
                legendInfo_t{end+1} = "V_c (V)";
            end
            legend(app.TimeAxes,legendInfo_t);
            hold(app.TimeAxes,"off");
            % Frequency Domain
            TF_r = tf([R 0],[L R 1/C]);
            TF_l = tf([L 0 0],[L R 1/C]);
            TF_c = tf([0 1],[L*C R*C 1]);
            cla(app.FrequencyAxes);
            hold(app.FrequencyAxes,"on");
            legendInfo_s = cell(0,1);
            if app.HsVRCheckBox.Value
                bode(app.FrequencyAxes,TF_r,'r');
                legendInfo_s{end+1} = "H_r(s) (V)";
            end
            if app.HsVLCheckBox.Value
                bode(app.FrequencyAxes,TF_l,'g');
                legendInfo_s{end+1} = "H_l(s) (V)";
            end
            if app.HsVCCheckBox.Value
                bode(app.FrequencyAxes,TF_c,'b');
                legendInfo_s{end+1} = "H_c(s) (V)";
            end
            legend(app.FrequencyAxes,legendInfo_s);
            grid(app.FrequencyAxes,"on")
            hold(app.FrequencyAxes,"on");
        end
        
        
        function Parallel_results(app)
            cla(app.TimeAxes);
            cla(app.FrequencyAxes);
            syms V(t) R L C I_r(t) I_l(t) I_c(t) I_s(t);
            Amp = app.VsEditField.Value;
            R = app.REditField.Value;
            L = app.LEditField.Value;
            C = app.CEditField.Value;
            f = app.frequencyEditField.Value;
            u(t) = piecewise(t >= 0, 1,0);
            if app.InputSignalDropDown.Value == "Step"
                I(t) = Amp*heaviside(t);
                app.Max = Amp;
                ylim(app.TimeAxes,[-app.Max*2/5 app.Max*7/5]);
                alpha = 2*(R/L);
                omega_0 = sqrt(1/(L*C));
                app.omega_d = abs(sqrt(omega_0^2-alpha^2));
                xlim(app.TimeAxes,[-pi/app.omega_d 6*pi/app.omega_d]);
            elseif app.InputSignalDropDown.Value == "Sin"
                I(t) = Amp*sin(2*pi*t*f);
                app.Max = Amp;
                ylim(app.TimeAxes,[-app.Max*6/5 app.Max*6/5]);
                app.omega_d = f;
                xlim(app.TimeAxes,[-0.5/app.omega_d 3.5/app.omega_d]);
            end
            % solving V(t) ODE
            D2V(t) = diff(V,t,2);
            D1V(t) = diff(V,t,1);
            ODE = D2V + (1/(R*C))*D1V + (1/(L*C))*V(t) == diff(I(t),t,1)/C;
            IC = [V(0) == 0 D1V(0) == I(0)/C];
            V(t) = simplify(dsolve(ODE,IC));
            I_r(t) = V(t)/R;
            I_c(t) = C*diff(V,t,1);
            I_l(t) = I(t) - I_r(t) - I_c(t);
            % plot time domain
            legendInfo_t = cell(0,1);
            cla(app.TimeAxes);
            hold(app.TimeAxes,"on");
            if app.VsCheckBox.Value
                fplot(app.TimeAxes,I*u(t));
                legendInfo_t{end+1} = "I_s (A)";
            end
            if app.VRCheckBox.Value
                fplot(app.TimeAxes,I_r(t)*u(t),'r');
                legendInfo_t{end+1} = "I_r (A)";
            end
            if app.VLCheckBox.Value
                fplot(app.TimeAxes,I_l(t)*u(t),'g');
                legendInfo_t{end+1} = "I_l (A)";
            end
            if app.VCCheckBox.Value
                fplot(app.TimeAxes,I_c(t)*u(t),'b');
                legendInfo_t{end+1} = "I_c (A)";
            end
            legend(app.TimeAxes,legendInfo_t);
            hold(app.TimeAxes,"off");
            % Frequency Domain
            TF_r = tf([1 0], [R*C 1 R/L]);
            TF_l = tf([1],[L*C L/R 1]);
            TF_c = tf([1 0 0],[1 1/(R*C) 1/(L*C)]);
            cla(app.FrequencyAxes);
            hold(app.FrequencyAxes,"on");
            legendInfo_s = cell(0,1);
            if app.HsVRCheckBox.Value
                bode(app.FrequencyAxes,TF_r,'r');
                legendInfo_s{end+1} = "H_r(s) (A)";
            end
            if app.HsVLCheckBox.Value
                bode(app.FrequencyAxes,TF_l,'g');
                legendInfo_s{end+1} = "H_l(s) (A)";
            end
            if app.HsVCCheckBox.Value
                bode(app.FrequencyAxes,TF_c,'b');
                legendInfo_s{end+1} = "H_c(s) (A)";
            end
            legend(app.FrequencyAxes,legendInfo_s);
            grid(app.FrequencyAxes,"on")
            hold(app.FrequencyAxes,"on");
        end


        function Mode_Change(app)   % set dafualt value 
            if strcmpi(app.ModeSwitch.Value,'Series')
                if app.InputSignalDropDown.Value == "Sin"
                    app.VsEditField.Value = 5;
                    app.REditField.Value = 100;
                    app.LEditField.Value = 10e-3;
                    app.CEditField.Value = 4.7e-6;
                    app.frequencyEditField.Value = 1e+3;
                elseif app.InputSignalDropDown.Value == "Step"
                    app.VsEditField.Value = 5;
                    app.REditField.Value = 100;
                    app.LEditField.Value = 0.047;
                    app.CEditField.Value = 1e-6;
                end
            else
                if app.InputSignalDropDown.Value == "Sin"
                    app.VsEditField.Value = 10e-3;
                    app.REditField.Value = 10e3;
                    app.LEditField.Value = 10e-3;
                    app.CEditField.Value = 47e-3;
                    app.frequencyEditField.Value = 10;
                elseif app.InputSignalDropDown.Value == "Step"
                    app.VsEditField.Value = 1;
                    app.REditField.Value = 10;
                    app.LEditField.Value = 1e-3;
                    app.CEditField.Value = 4.7e-3;
                    app.frequencyEditField.Value = 10;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            if strcmpi(app.ModeSwitch.Value,'Series')
                Series_results(app);
            else
                Parallel_results(app);
            end
        end

        % Value changed function: InputSignalDropDown
        function InputSignalDropDownValueChanged(app, event)
            Mode_Change(app);
        end

        % Value changed function: ModeSwitch
        function ModeSwitchValueChanged(app, event)
            Mode_Change(app);
            if strcmpi(app.ModeSwitch.Value,'Series')
                ylabel(app.TimeAxes, 'V(t)');
                app.VsEditFieldLabel.Text = 'Vs';
                app.VLabel.Text = "V";
                app.VsCheckBox.Text = 'Vs';
                app.VRCheckBox.Text = 'VR';
                app.VLCheckBox.Text = 'VL';
                app.VCCheckBox.Text = 'VC';
            else
                ylabel(app.TimeAxes, 'I(t)');
                app.VsEditFieldLabel.Text = 'Is';
                app.VLabel.Text = "A";
                app.VsCheckBox.Text = 'Is';
                app.VRCheckBox.Text = 'IR';
                app.VLCheckBox.Text = 'IL';
                app.VCCheckBox.Text = 'IC';
            end
            cla(app.TimeAxes);
            cla(app.FrequencyAxes);
        end

        % Button pushed function: ButtonPlus
        function ButtonPlusPushed(app, event)
            app.omega_d = app.omega_d*2;
            xlim(app.TimeAxes,[-pi/app.omega_d 6*pi/app.omega_d]);
        end

        % Button pushed function: ButtonMinus
        function ButtonMinusPushed(app, event)
            app.omega_d = app.omega_d/2;
            xlim(app.TimeAxes,[-pi/app.omega_d 6*pi/app.omega_d]);
        end

        % Button pushed function: ButtonPlus_2
        function ButtonPlus_2Pushed(app, event)
            app.Max = app.Max/2;
            ylim(app.TimeAxes,[-app.Max*6/5 app.Max*6/5]);
        end

        % Button pushed function: ButtonMinus_2
        function ButtonMinus_2Pushed(app, event)
            app.Max = app.Max*2;
            ylim(app.TimeAxes,[-app.Max*6/5 app.Max*6/5]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TimeAxes = uiaxes(app.UIFigure);
            title(app.TimeAxes, 'Time Domain')
            xlabel(app.TimeAxes, 't')
            ylabel(app.TimeAxes, 'V(t)')
            zlabel(app.TimeAxes, 'Z')
            app.FrequencyAxes = uiaxes(app.UIFigure);
            title(app.FrequencyAxes, 'Frequency Domain')
            xlabel(app.FrequencyAxes, 'Frequency (Rad/s)')
            ylabel(app.FrequencyAxes, 'Y')
            zlabel(app.FrequencyAxes, 'Z')
            app.VsEditFieldLabel = uilabel(app.UIFigure);
            app.VsEditFieldLabel.Text = 'Vs';
            app.VsEditField = uieditfield(app.UIFigure, 'numeric');
            app.VsEditField.Value = 5;
            app.REditFieldLabel = uilabel(app.UIFigure);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.UIFigure, 'numeric');
            app.REditField.Value = 100;
            app.LEditFieldLabel = uilabel(app.UIFigure);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.UIFigure, 'numeric');
            app.LEditField.Value = 0.01;
            app.CEditFieldLabel = uilabel(app.UIFigure);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.UIFigure, 'numeric');
            app.CEditField.Value = 4.7e-06;
            app.InputSignalDropDownLabel = uilabel(app.UIFigure);
            app.InputSignalDropDownLabel.Text = 'Input Signal';
            app.InputSignalDropDown = uidropdown(app.UIFigure);
            app.InputSignalDropDown.Items = {'Sin', 'Step'};
            app.InputSignalDropDown.ValueChangedFcn = createCallbackFcn(app, @InputSignalDropDownValueChanged, true);
            app.InputSignalDropDown.Value = 'Sin';
            app.VsCheckBox = uicheckbox(app.UIFigure);
            app.VsCheckBox.Text = 'Vs';
            app.VsCheckBox.Value = true;
            app.VRCheckBox = uicheckbox(app.UIFigure);
            app.VRCheckBox.Text = 'VR';
            app.VRCheckBox.Value = true;
            app.VLCheckBox = uicheckbox(app.UIFigure);
            app.VLCheckBox.Text = 'VL';
            app.VLCheckBox.Value = true;
            app.VCCheckBox = uicheckbox(app.UIFigure);
            app.VCCheckBox.Text = 'VC';
            app.VCCheckBox.Value = true;
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.frequencyEditFieldLabel = uilabel(app.UIFigure);
            app.frequencyEditFieldLabel.Text = 'frequency';
            app.frequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.frequencyEditField.Value = 1000;
            app.HsVRCheckBox = uicheckbox(app.UIFigure);
            app.HsVRCheckBox.Text = 'H(s) - VR';
            app.HsVRCheckBox.Value = true;
            app.HsVLCheckBox = uicheckbox(app.UIFigure);
            app.HsVLCheckBox.Text = 'H(s) - VL';
            app.HsVLCheckBox.Value = true;
            app.HsVCCheckBox = uicheckbox(app.UIFigure);
            app.HsVCCheckBox.Text = 'H(s) - VC';
            app.HsVCCheckBox.Value = true;
            app.ModeSwitchLabel = uilabel(app.UIFigure);
            app.ModeSwitchLabel.Text = 'Mode';
            app.ModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.ModeSwitch.Items = {'Series', 'Parallel'};
            app.ModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ModeSwitchValueChanged, true);
            app.ModeSwitch.Value = 'Series';
            app.VLabel = uilabel(app.UIFigure);
            app.VLabel.Text = 'V';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Ω';
            app.HLabel = uilabel(app.UIFigure);
            app.HLabel.Text = 'H';
            app.FLabel = uilabel(app.UIFigure);
            app.FLabel.Text = 'F';
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.ButtonPlus = uibutton(app.UIFigure, 'push');
            app.ButtonPlus.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlusPushed, true);
            app.ButtonPlus.Text = {'+'; ''};
            app.ButtonMinus = uibutton(app.UIFigure, 'push');
            app.ButtonMinus.ButtonPushedFcn = createCallbackFcn(app, @ButtonMinusPushed, true);
            app.ButtonMinus.Text = {'-'; ''};
            app.ButtonPlus_2 = uibutton(app.UIFigure, 'push');
            app.ButtonPlus_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlus_2Pushed, true);
            app.ButtonPlus_2.Text = {'+'; ''};
            app.ButtonMinus_2 = uibutton(app.UIFigure, 'push');
            app.ButtonMinus_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonMinus_2Pushed, true);
            app.ButtonMinus_2.Text = {'-'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RLC_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end